classdef PatientsDisplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DentalCenterUIFigure            matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SaveToTableButton               matlab.ui.control.Button
        Scenario3EvenlyspacedarrivalswithineachintervalLabel  matlab.ui.control.Label
        Scenario2Alternatinggroupsizes4then3atfixedintervalsLabel  matlab.ui.control.Label
        Scenario1FixedintervalsconsistentgroupsizeLabel  matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        NumberofExperimentsEditField    matlab.ui.control.NumericEditField
        NumberofExperimentsEditFieldLabel  matlab.ui.control.Label
        D                               matlab.ui.container.Panel
        ScenarioSettingsPanel           matlab.ui.container.Panel
        ClosingIntervalminEditField     matlab.ui.control.NumericEditField
        ClosingIntervalminEditFieldLabel  matlab.ui.control.Label
        AppointmentIntervalminEditField  matlab.ui.control.NumericEditField
        AppointmentIntervalminEditFieldLabel  matlab.ui.control.Label
        PatientsperIntervalEditField    matlab.ui.control.NumericEditField
        PatientsperIntervalEditFieldLabel  matlab.ui.control.Label
        ScenarioDropDown                matlab.ui.control.DropDown
        ScenarioDropDownLabel           matlab.ui.control.Label
        DoctorSelectionMethodDropDown   matlab.ui.control.DropDown
        DoctorSelectionMethodDropDownLabel  matlab.ui.control.Label
        SimultionTimeminEditField       matlab.ui.control.NumericEditField
        SimultionTimeminEditFieldLabel  matlab.ui.control.Label
        DoctorsDropDown                 matlab.ui.control.DropDown
        DoctorsDropDownLabel            matlab.ui.control.Label
        InputParametersLabel            matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        PlotTab                         matlab.ui.container.Tab
        minutesLabel_2                  matlab.ui.control.Label
        AverageWaitingTimeinQueueinRunLabel  matlab.ui.control.Label
        patientsLabel_2                 matlab.ui.control.Label
        OverallAveragePatientsTreatedLabel  matlab.ui.control.Label
        patientsLabel                   matlab.ui.control.Label
        PatientsTreatedinRunLabel       matlab.ui.control.Label
        ShowOverallUtilizationCheckBox  matlab.ui.control.CheckBox
        RunSpinner                      matlab.ui.control.Spinner
        RunSpinnerLabel                 matlab.ui.control.Label
        minutesLabel                    matlab.ui.control.Label
        OverallAverageWaitingTimeinQueueLabel  matlab.ui.control.Label
        GraphSwitch                     matlab.ui.control.Switch
        UIAxes                          matlab.ui.control.UIAxes
        DataTab                         matlab.ui.container.Tab
        RunSpinner_2                    matlab.ui.control.Spinner
        RunSpinner_2Label               matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        HistoryTab                      matlab.ui.container.Tab
        UITable2                        matlab.ui.control.Table
        CMCIRunsTab                     matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % The app displays the data by using the scatter plot, histogram, and table.
    % It makes use of tabs to separate the ploting options output from the table display of the data.
    % There are several graphical elements used such as checkboxes, slider, switch, dropdown, and radiobutton group.
    % The data used in the app is shipped with the product.
    
    properties (Access = private)
        % Declare properties of the PatientsDisplay class.
        AverageWaitingTime
        QueueLengthHistory
        DoctorsUtilization
        DoctorsUtilizationRun

        TotalSimulationTime

        BinWidth
        Histogram = gobjects(0)
        displayedIndices

        RunsResult
        ExperimentsInformation        
        SharedSpinnerValue = 1

        priorites
        id

        totalTreatedPatientsMatrix;
        waitingTimes;
        waitingTimesAll;
    end
    
    methods (Access = private)

        function plotWaitingTimeAnalysisWithCI(app, waitingTimeForAllRuns)
            numRuns = length(waitingTimeForAllRuns);
            if numRuns > 1  % Ensure there's at least two runs to compute a CI
                % Preallocate arrays for average time and confidence intervals calculations
                averageTimeInTime = zeros(numRuns, 1);
                upperCI = zeros(numRuns, 1);
                lowerCI = zeros(numRuns, 1);
        
                % Calculate cumulative mean and confidence intervals from index 1 to i
                for i = 1:numRuns
                    sampleData = waitingTimeForAllRuns(1:i);
                    currentMean = mean(sampleData);
                    currentStd = std(sampleData);
                    currentCI = 1.96 * (currentStd / sqrt(i));  % 95% CI
        
                    averageTimeInTime(i) = currentMean;
                    if i > 1  % Only calculate CI from the second run onwards
                        upperCI(i) = currentMean + currentCI;
                        lowerCI(i) = currentMean - currentCI;
                    end
                end
        
                % Clear previous plots
                cla(app.UIAxes2);
        
                % Plot cumulative mean waiting time
                plot(app.UIAxes2, 1:numRuns, averageTimeInTime, 'LineWidth', 2, 'Color', 'b');
        
                % Hold on to plot the confidence interval
                hold(app.UIAxes2, 'on');
                
                % Plot confidence intervals from the second run onwards
                plot(app.UIAxes2, 2:numRuns, upperCI(2:end), '--r', 'LineWidth', 1.5);  % Upper Confidence Interval
                plot(app.UIAxes2, 2:numRuns, lowerCI(2:end), '--r', 'LineWidth', 1.5);  % Lower Confidence Interval
        
                % Set plot details
                title(app.UIAxes2, 'Cumulative Mean and Confidence Intervals Over Runs');
                xlabel(app.UIAxes2, 'Run Number');
                ylabel(app.UIAxes2, 'Waiting Time');
                legend(app.UIAxes2, {'Cumulative Mean', 'Upper 95% CI', 'Lower 95% CI'}, 'Location', 'best');
                grid(app.UIAxes2, 'on');
        
                % Release the hold
                hold(app.UIAxes2, 'off');
            else
                disp('At least two runs are required to analyze with confidence intervals.');
                cla(app.UIAxes2);
                title(app.UIAxes2, 'Insufficient data available');
            end
        end


        function plotWaitingTimeAnalysis(app, waitingTimeForAllRuns, fromRun)
            numRuns = length(waitingTimeForAllRuns);
            if numRuns > fromRun
                % Preallocate array for average time calculations
                averageTimeInTime = zeros(numRuns, 1);
                
                % Calculate cumulative mean from index 1 to i
                for i = 1:numRuns
                    averageTimeInTime(i) = mean(waitingTimeForAllRuns(1:i));
                end
        
                % Analyzing data from fromRun onward
                stableData = waitingTimeForAllRuns(fromRun+1:end);
                meanTime = mean(stableData);
                stdTime = std(stableData);
                confInterval = 1.96 * (stdTime / sqrt(length(stableData)));
        
                % Display analysis results
                disp(['Mean waiting time after ', num2str(fromRun), ' runs: ', num2str(meanTime), ' ± ', num2str(confInterval)]);
                
                % Clear previous plots
                cla(app.UIAxes2);
        
                % Plot cumulative mean waiting time
                plot(app.UIAxes2, 1:numRuns, averageTimeInTime, 'LineWidth', 2);
        
                % Hold on to plot the confidence interval
                hold(app.UIAxes2, 'on');
                
                % Plot mean and confidence interval from fromRun onward
                errorbar(app.UIAxes2, fromRun+1:numRuns, meanTime*ones(numRuns-fromRun, 1), confInterval*ones(numRuns-fromRun, 1), 'o');
        
                % Set plot details
                title(app.UIAxes2, 'Average and Confident Waiting Time Analysis');
                xlabel(app.UIAxes2, 'Run Number');
                ylabel(app.UIAxes2, 'Waiting Time');
                legend(app.UIAxes2, 'Cumulative Mean', '95% Confidence Interval', 'Location', 'best');
                grid(app.UIAxes2, 'on');
                
                % Release the hold
                hold(app.UIAxes2, 'off');
            else
                disp(['Not enough runs to analyze stability post ', num2str(fromRun), ' runs.']);
                cla(app.UIAxes2);
                title(app.UIAxes2, 'Not enough data available');
            end
        end


        function analyzeWaitingTime(app, waitingTimeForAllRuns, fromRun)
            numRuns = length(waitingTimeForAllRuns);
            if numRuns > fromRun
                % Analyzing data from run 501 onward
                stableData = waitingTimeForAllRuns(fromRun+1:end);
                meanTime = mean(stableData);
                stdTime = std(stableData);
                confInterval = 1.96 * (stdTime / sqrt(length(stableData)));
        
                % Display analysis results
                disp(['Mean waiting time after ', num2str(fromRun), ' runs: ', num2str(meanTime), ' ± ', num2str(confInterval)]);
        
                % Plot mean and confidence interval on app.UIAxes2
                cla(app.UIAxes2);
                errorbar(app.UIAxes2, fromRun+1:numRuns, meanTime*ones(numRuns-fromRun, 1), confInterval*ones(numRuns-500, 1), 'o');
                title(app.UIAxes2, 'Mean Waiting Time with Confidence Interval');
                xlabel(app.UIAxes2, 'Run Number');
                ylabel(app.UIAxes2, 'Waiting Time');
                grid(app.UIAxes2, 'on');
            else
                disp(['Not enough runs to analyze stability post ',num2str(fromRun),' runs.']);
            end
        end

        function plotAverageWaitingTimeInTime(app, waitingTimeForAllRuns)
            if ~isempty(waitingTimeForAllRuns)
                % Preallocate array for average time calculations
                numRuns = length(waitingTimeForAllRuns);
                averageTimeInTime = zeros(numRuns, 1);
        
                % Calculate cumulative mean from index 1 to i
                for i = 1:numRuns
                    averageTimeInTime(i) = mean(waitingTimeForAllRuns(1:i));
                end
        
                % Plot the results on the specified UIAxes2
                plot(app.UIAxes2, averageTimeInTime, 'LineWidth', 2);
                title(app.UIAxes2, 'Average Waiting Time Over Runs');
                xlabel(app.UIAxes2, 'Run Number');
                ylabel(app.UIAxes2, 'Average Waiting Time');
                grid(app.UIAxes2, 'on');
        
                % Optionally, set y-axis ticks to reflect the range of data
                minY = min(averageTimeInTime);
                maxY = max(averageTimeInTime);
                yticks(app.UIAxes2, linspace(minY, maxY, 5));  % Adjust number of ticks as needed
        
                % Make sure grid lines are visible for clarity
                grid(app.UIAxes2, 'on');
            else
                % Handle the case where there is no data to plot
                cla(app.UIAxes2);
                title(app.UIAxes2, 'No data available');
            end
        end


        function plotQueueLength(app, queueLengthHistory)
            % Check if the queue length history is not empty
            if ~isempty(queueLengthHistory)
                % Create a staircase plot on the provided axes
                stairs(app.UIAxes, queueLengthHistory(:,1), queueLengthHistory(:,2), 'LineWidth', 2);
                title(app.UIAxes, 'Queue Length Over Time');
                xlabel(app.UIAxes, 'Time (minutes)');
                ylabel(app.UIAxes, 'Number of Patients in Queue');
                grid(app.UIAxes, 'on');
                
                % Setting y-axis ticks to include only integer values
                maxY = max(queueLengthHistory(:,2));
                yticks(app.UIAxes, 0:maxY);  % Only show whole number ticks        
        
                % Ensure that grid lines are visible for both axes
                grid(app.UIAxes, 'on');
            end
        end

        function plotDoctorUtilization(app, totalSimulationTime)
            utilizationPercentages = app.DoctorsUtilizationRun;
        
            % Initialize color matrix
            colors = zeros(length(utilizationPercentages), 3);
            
            % Define colors based on utilization percentages
            for i = 1:length(utilizationPercentages)
                if utilizationPercentages(i) >= 90
                    colors(i, :) = [1 0 0];  % Red for overutilized
                elseif utilizationPercentages(i) >= 70
                    colors(i, :) = [0 1 0];  % Green for optimal
                elseif utilizationPercentages(i) >= 50
                    colors(i, :) = [0 0.5 1];  % Light blue for underutilized
                else
                    colors(i, :) = [0.6 0.6 0.6];  % Gray for significantly underutilized
                end
            end
        
            % Create bar plot with flat colors
            b = bar(app.UIAxes, utilizationPercentages, 'FaceColor', 'flat');
            b.CData = colors;  % Apply the colors to the bars
            title(app.UIAxes, 'Doctor Utilization Rates');
            xlabel(app.UIAxes, 'Doctor ID');
            ylabel(app.UIAxes, 'Utilization (%)');
            xticks(app.UIAxes, 1:length(utilizationPercentages));
            grid(app.UIAxes, 'on');
        
            % Adjusting y-axis limits to accommodate text and legend
            ylim(app.UIAxes, [0 max(utilizationPercentages) * 1.2]);  % Adding 20% padding
        
            % Adding text labels above bars
            for i = 1:length(utilizationPercentages)
                text(app.UIAxes, b.XData(i), b.YData(i), sprintf('%.1f%%', b.YData(i)), ...
                    'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom');
            end
        
            % Add a legend by plotting empty data with the same colors used for categories
            hold(app.UIAxes, 'on');
            h = zeros(4, 1);
            h(1) = bar(app.UIAxes, NaN, 'FaceColor', [1 0 0]);  % Overutilized
            h(2) = bar(app.UIAxes, NaN, 'FaceColor', [0 1 0]);  % Optimal
            h(3) = bar(app.UIAxes, NaN, 'FaceColor', [0 0.5 1]);  % Underutilized
            h(4) = bar(app.UIAxes, NaN, 'FaceColor', [0.6 0.6 0.6]);  % Significantly Underutilized
        
            legend(app.UIAxes, h, {'Overutilized (>=90%)', 'Optimal (70-89%)', 'Underutilized (50-69%)', 'Significantly Underutilized (<50%)'}, ...
                'Location', 'northeastoutside');  % Positioning legend outside the northeast of the axes
        
            hold(app.UIAxes, 'off');
        end


        function promptInputs(app, numInputs)
            numInputs = str2num(numInputs);
            % Ensure numInputs is a positive integer
            if ~isnumeric(numInputs) || numInputs <= 0 || mod(numInputs, 1) ~= 0
                error('numInputs must be a positive integer.');
            end

            % Create a UI figure for the dialog box
            d = uifigure('Position',[300 300 250 150 + 30*numInputs],'Name','Set Priorities');
        
            % Initialize arrays for labels and edit fields
            lbls = gobjects(numInputs+1, 1);
            edits = gobjects(numInputs, 1);
        
            % Dynamically create labels and numeric edit fields based on numInputs
            for i = 1:numInputs
                lbls(i) = uilabel(d, 'Position',[10 120 + 30*(numInputs-i) 100 20], 'Text',['Doctor ' num2str(i) ':']);
                edits(i) = uieditfield(d, 'numeric', 'Position',[120 120 + 30*(numInputs-i) 100 20]);
            end
        
            lbls(numInputs+1) = uilabel(d, 'Position',[10 120 + 30 * numInputs 300 20], 'Text',['Doctor with highest priority will be prioritized']);
            % Add a button to submit the data

            btn = uibutton(d, 'push', 'Position',[75 30 100 25], 'Text','Submit');
            btn.ButtonPushedFcn = @(btn, event) submitInputs(app, edits, d);
        
            % Wait for d to close before running to completion
            uiwait(d);
        end
        
        function submitInputs(app, edits, dialogBox)
            % Retrieve values from edit fields
            inputs = zeros(length(edits), 1);
            for i = 1:length(edits)
                inputs(i) = edits(i).Value; % Assumes numeric input
            end
        
            app.priorites = inputs;
        
            % Close the dialog after submission
            delete(dialogBox);
        end    


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.id = 1;

            app.UIAxes.Visible = "off";        
            app.UIAxes2.Visible = "off";
            app.RunSpinner.Visible = "off";
            app.RunSpinner_2.Visible = "off";
            app.GraphSwitch.Visible = "off";
            app.RunSpinnerLabel.Visible = "off";
            app.RunSpinner_2Label.Visible = "off";
            app.OverallAverageWaitingTimeinQueueLabel.Visible = "off";
            app.minutesLabel.Visible = "off";
            app.ShowOverallUtilizationCheckBox.Visible = "off";
            app.PatientsTreatedinRunLabel.Visible = "off";            
            app.patientsLabel.Visible = "off";

            app.minutesLabel_2.Visible = "off";
            app.AverageWaitingTimeinQueueinRunLabel.Visible = "off";

            app.OverallAveragePatientsTreatedLabel.Visible = "off";
            app.patientsLabel_2.Visible = "off";

            app.UITable.ColumnWidth = {60, 100, 70, 80, 80, 80 ,100};

            information = table(...
                'Size', [0, 7], ...
                'VariableTypes', {'int32', 'string', 'string', 'string', 'logical', 'logical', 'int32'}, ...
                'VariableNames', {'time', 'type', 'doctor', 'patient', 'complication', 'urgent', 'queueSize'});
            
            % Now assign this table to the UITable in your app
            app.UITable.Data = information;

            information = table(...
            'Size', [0, 11], ...
                'VariableTypes', {'int32', 'string', 'double', 'double', 'string', 'int32', 'string','int32','int32','int32','int32'});

            app.UITable2.Data = information;
            
            % Load the data.
            % % % load("patients.mat","LastName","Gender","Smoker","Age","Height","Weight","Diastolic","Systolic","Location");
            % % % 
            % % % % Store the data in a table and display it in one of the App's tabs.
            % % % app.Data = table(LastName,Gender,Smoker,Age,Height,Weight,Diastolic,Systolic,Location);
            % % % app.UITable.Data = app.Data;
            % % % app.BinWidth = app.BinWidthSlider.Value;
            % % % 
            % % % % Update the axes with the corresponding data.
            % % % updateSelectedGenders(app)
            % % % refreshplot(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.DentalCenterUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {465, 465};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {281, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function: GraphSwitch, UITable
        function refreshplot(app, event)
            % Ensure that the GraphSwitch value is correctly retrieved
            if ~isempty(app.RunsResult)
                app.UIAxes.Visible = "on";
                app.UIAxes2.Visible = "on";
                app.RunSpinner.Visible = "on";
                app.RunSpinner_2.Visible = "on";
                app.GraphSwitch.Visible = "on";
                app.RunSpinnerLabel.Visible = "on";
                app.RunSpinner_2Label.Visible = "on";

                cla(app.UIAxes, 'reset');
                if strcmp(app.GraphSwitch.Value, 'Queue Length')
                    app.ShowOverallUtilizationCheckBox.Visible = "off";
                    % Assuming 'app.UIAxes' is the name of the axes UI component in your app
                    app.plotQueueLength(app.QueueLengthHistory);
                elseif strcmp(app.GraphSwitch.Value, 'Doctors Utilization')
                    app.ShowOverallUtilizationCheckBox.Visible = "on";
                    app.plotDoctorUtilization(app.TotalSimulationTime);
                end
            end


            % % Genders = app.SelectedGenders;
            % % Colors = app.SelectedColors;
            % % 
            % % % Start with a fresh plot
            % % cla(app.UIAxes)
            % % hold(app.UIAxes,"on")
            % % app.Histogram = gobjects(0);
            % % 
            % % % Select relevant segment of data
            % % xdata = app.Data.Weight;
            % % ydata = app.Data.(app.GraphSwitch.Value);
            % % 
            % % % Filter the data according to the controls
            % % filterData(app);
            % % 
            % % % Create either a scatter plot or histogram, based on selection
            % % switch app.ButtonGroup.SelectedObject.Text
            % % 
            % %     case 'Scatter'
            % %         % Build a scatter plot for each selected gender
            % %         for ii = 1:length(Genders)
            % %             selectedpatients = ((app.Data.Gender == Genders(ii)) & (app.displayedIndices));
            % %             scatter(app.UIAxes,xdata((selectedpatients)),ydata(selectedpatients),Colors{ii});
            % %         end
            % %         annotateScatterPlot(app)
            % % 
            % %     case 'Histogram'
            % %         % Build a histogram for each selected gender
            % %         for ii = 1:length(Genders)
            % %             selectedpatients = ((app.Data.Gender == Genders(ii)) & (app.displayedIndices));
            % %             NBins = numhistbins(app,ydata(selectedpatients));
            % %             h = histogram(app.UIAxes,ydata(selectedpatients),NBins,"BinLimits",[floor(min(ydata)) ceil(max(ydata))]);
            % %             h.EdgeColor = Colors{ii};
            % %             h.FaceColor = Colors{ii};
            % %             app.Histogram = [app.Histogram h];
            % %         end
            % %         annotateHistogram(app)
            % % 
            % % end
            % % 
            % % % Update the table to show only the data that satisfies the controls
            % % app.UITable.Data = app.Data(app.displayedIndices,:);
            % % drawnow;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.RunButton.Enable = "off";
            numDoctors = str2num(app.DoctorsDropDown.Value); %Must be int
            doctorSelectionMethod = app.DoctorSelectionMethodDropDown.Value;
            simulationTime = app.SimultionTimeminEditField.Value;
            app.TotalSimulationTime = simulationTime;

            scenarioType = str2num(app.ScenarioDropDown.Value); %Must be int
            appoitmentIntervalMin = app.AppointmentIntervalminEditField.Value;
            patiensPerInterval = app.PatientsperIntervalEditField.Value;
            closingInterval = app.ClosingIntervalminEditField.Value;

            runs = app.NumberofExperimentsEditField.Value;

            app.RunSpinner.Limits = [1,runs];        
            app.RunSpinner_2.Limits = [1,runs];        


            params = struct('numDoctors', numDoctors, 'totalSimulationTime', simulationTime, ...
                            'scenarioNumber', scenarioType, 'patientsPerInterval', patiensPerInterval, ...
                            'appointmentInterval', appoitmentIntervalMin, 'endBuffer', closingInterval);
            simManager = SimulationManager(runs,params,doctorSelectionMethod,app.priorites);


            simManager.runExperiments();
            app.RunsResult = simManager.getFinalResults();
            app.DoctorsUtilization = simManager.getDoctorsUtilization();
            app.ExperimentsInformation = simManager.getInformation();
            app.waitingTimes = simManager.getAverageWaitingTimes();
            app.waitingTimesAll = simManager.getAverageWaitingTimeForAllRuns();
            %app.plotAverageWaitingTimeInTime(app.waitingTimesAll);
            %app.analyzeWaitingTime(app.waitingTimesAll,200);
            app.plotWaitingTimeAnalysisWithCI(app.waitingTimesAll);
            %app.plotWaitingTimeAnalysis(app.waitingTimesAll,fromRun);

            app.minutesLabel_2.Text = num2str(app.waitingTimes(1)) + " minutes";

            app.QueueLengthHistory = app.RunsResult(1).QueueLengthHistory;
            app.DoctorsUtilizationRun = app.DoctorsUtilization(:,1);

            app.OverallAverageWaitingTimeinQueueLabel.Visible = "on";
            app.minutesLabel.Visible = "on";
            app.PatientsTreatedinRunLabel.Visible = "on";
            app.minutesLabel_2.Visible = "on";
            app.AverageWaitingTimeinQueueinRunLabel.Visible = "on";
            app.OverallAveragePatientsTreatedLabel.Visible = "on";
            app.patientsLabel.Visible = "on";
            app.patientsLabel_2.Visible = "on";
            app.minutesLabel.Text = num2str(app.RunsResult(1).AverageWaitingTime) + " minutes";
            app.totalTreatedPatientsMatrix = simManager.getTotalTreatedPatients();
            app.patientsLabel.Text = num2str(app.totalTreatedPatientsMatrix(1)) + " patients";
            app.patientsLabel_2.Text = num2str(mean(app.totalTreatedPatientsMatrix)) + " patients";
            app.refreshplot();

            app.UITable.Data = app.ExperimentsInformation{1};
            app.RunButton.Enable = "on";
            %simManager.displayResults();

        end

        % Value changed function: RunSpinner
        function RunListBoxValueChanged(app, event)

            newValue = app.RunSpinner.Value;

            app.RunSpinner_2.Value = newValue;     % Synchronize Spinner2

            if ~isempty(app.RunsResult)
                index = app.RunSpinner.Value;
                treatedPatients = app.totalTreatedPatientsMatrix;
                app.patientsLabel.Text = num2str(treatedPatients(index)) + " patients";
                app.minutesLabel_2.Text = num2str(app.waitingTimes(index)) + " minutes";
                app.QueueLengthHistory = app.RunsResult(index).QueueLengthHistory;
                app.DoctorsUtilizationRun = app.DoctorsUtilization(:,index);
                app.refreshplot();
                app.UITable.Data = app.ExperimentsInformation{index};
            end
    
        end

        % Value changed function: ShowOverallUtilizationCheckBox
        function ShowOverallUtilizationCheckBoxValueChanged(app, event)
            value = app.ShowOverallUtilizationCheckBox.Value;
            if(value)
                app.DoctorsUtilizationRun = app.RunsResult(1).DoctorUtilizations;
            else
                index = app.RunSpinner.Value;
                app.DoctorsUtilizationRun = app.DoctorsUtilization(:,index);
            end
            app.refreshplot();
        end

        % Value changed function: RunSpinner_2
        function RunSpinner_2ValueChanged(app, event)
            newValue = app.RunSpinner_2.Value;            

            app.RunSpinner.Value = newValue;     % Synchronize Spinner2

            if ~isempty(app.RunsResult)
                index = app.RunSpinner.Value;
                treatedPatients = app.totalTreatedPatientsMatrix;
                app.patientsLabel.Text = num2str(treatedPatients(index)) + " patients";
                app.minutesLabel_2.Text = num2str(app.waitingTimes(index)) + " minutes";
                app.QueueLengthHistory = app.RunsResult(index).QueueLengthHistory;
                app.DoctorsUtilizationRun = app.DoctorsUtilization(:,index);
                app.refreshplot();
                app.UITable.Data = app.ExperimentsInformation{index};
            end
        end

        % Value changed function: DoctorSelectionMethodDropDown
        function DoctorSelectionMethodDropDownValueChanged(app, event)
            selectedItem = app.DoctorSelectionMethodDropDown.Value;
            
            % Check if the selected item is the one that should trigger the popup
            if strcmp(selectedItem, 'priority')
                % Call the function to create the input dialog
                numInputs = app.DoctorsDropDown.Value; 
                promptInputs(app, numInputs);
            end
            
        end

        % Button pushed function: SaveToTableButton
        function SaveToTableButtonPushed(app, event)
            % Get the existing data from the table
            if ~isempty(app.RunsResult)
                currentData = app.UITable2.Data;
                newRow = {app.id, app.DoctorSelectionMethodDropDown.Value, ...
                            mean(app.totalTreatedPatientsMatrix), ...
                            app.RunsResult(1).AverageWaitingTime, app.DoctorSelectionMethodDropDown.Value, ...
                            app.TotalSimulationTime, app.ScenarioDropDown.Value, ...
                            app.AppointmentIntervalminEditField.Value, ...
                            app.PatientsperIntervalEditField.Value, ...
                            app.ClosingIntervalminEditField.Value, ...
                            app.NumberofExperimentsEditField.Value};
                
                % Append the new row
                % If currentData is an empty array, initialize it properly
                if isempty(currentData)
                    currentData = newRow;  % If it's the first row being added
                else
                    currentData = [currentData; newRow];  % Append the new row
                end
                
                % Set the updated data back to the table
                app.UITable2.Data = currentData;
                
                app.id = app.id + 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DentalCenterUIFigure = uifigure('Visible', 'off');
            app.DentalCenterUIFigure.Name = 'DentalCenter';
            app.DentalCenterUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.DentalCenterUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InputParametersLabel = uilabel(app.LeftPanel);
            app.InputParametersLabel.Text = 'Input Parameters';
            app.D = uipanel(app.LeftPanel);
            app.D.Title = 'Clinic Settings';
            app.DoctorsDropDownLabel = uilabel(app.D);
            app.DoctorsDropDownLabel.Text = 'Doctors';
            app.DoctorsDropDown = uidropdown(app.D);
            app.DoctorsDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.DoctorsDropDown.Value = '4';
            app.SimultionTimeminEditFieldLabel = uilabel(app.D);
            app.SimultionTimeminEditFieldLabel.Text = 'Simultion Time (min)';
            app.SimultionTimeminEditField = uieditfield(app.D, 'numeric');
            app.SimultionTimeminEditField.Value = 360;
            app.DoctorSelectionMethodDropDownLabel = uilabel(app.D);
            app.DoctorSelectionMethodDropDownLabel.Text = 'Doctor Selection Method';
            app.DoctorSelectionMethodDropDown = uidropdown(app.D);
            app.DoctorSelectionMethodDropDown.Items = {'minWorkload', 'random', 'priority', 'circular'};
            app.DoctorSelectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DoctorSelectionMethodDropDownValueChanged, true);
            app.DoctorSelectionMethodDropDown.Value = 'minWorkload';
            app.ScenarioSettingsPanel = uipanel(app.D);
            app.ScenarioSettingsPanel.Title = 'Scenario Settings';
            app.ScenarioDropDownLabel = uilabel(app.ScenarioSettingsPanel);
            app.ScenarioDropDownLabel.Text = 'Scenario';
            app.ScenarioDropDown = uidropdown(app.ScenarioSettingsPanel);
            app.ScenarioDropDown.Items = {'1', '2', '3'};
            app.ScenarioDropDown.Value = '1';
            app.PatientsperIntervalEditFieldLabel = uilabel(app.ScenarioSettingsPanel);
            app.PatientsperIntervalEditFieldLabel.Text = 'Patients per Interval';
            app.PatientsperIntervalEditField = uieditfield(app.ScenarioSettingsPanel, 'numeric');
            app.PatientsperIntervalEditField.Value = 3;
            app.AppointmentIntervalminEditFieldLabel = uilabel(app.ScenarioSettingsPanel);
            app.AppointmentIntervalminEditFieldLabel.Text = 'Appointment Interval (min)';
            app.AppointmentIntervalminEditField = uieditfield(app.ScenarioSettingsPanel, 'numeric');
            app.AppointmentIntervalminEditField.Tooltip = {'Repeating interval in which patients arrive.'};
            app.AppointmentIntervalminEditField.Value = 30;
            app.ClosingIntervalminEditFieldLabel = uilabel(app.ScenarioSettingsPanel);
            app.ClosingIntervalminEditFieldLabel.Text = 'Closing Interval (min)';
            app.ClosingIntervalminEditField = uieditfield(app.ScenarioSettingsPanel, 'numeric');
            app.ClosingIntervalminEditField.Tooltip = {'Interval before end of simultion, where no arrival is triggered.'};
            app.NumberofExperimentsEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberofExperimentsEditFieldLabel.Text = 'Number of Experiments';
            app.NumberofExperimentsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofExperimentsEditField.Value = 3;
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Scenario1FixedintervalsconsistentgroupsizeLabel = uilabel(app.LeftPanel);
            app.Scenario1FixedintervalsconsistentgroupsizeLabel.Text = 'Scenario 1: Fixed intervals, consistent group size.';
            app.Scenario2Alternatinggroupsizes4then3atfixedintervalsLabel = uilabel(app.LeftPanel);
            app.Scenario2Alternatinggroupsizes4then3atfixedintervalsLabel.Text = {'Scenario 2: Alternating group sizes (4 then 3)'; '                   at fixed intervals.'};
            app.Scenario3EvenlyspacedarrivalswithineachintervalLabel = uilabel(app.LeftPanel);
            app.Scenario3EvenlyspacedarrivalswithineachintervalLabel.Text = {'Scenario 3: Evenly spaced arrivals '; '                   within each interval.'};
            app.SaveToTableButton = uibutton(app.LeftPanel, 'push');
            app.SaveToTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveToTableButtonPushed, true);
            app.SaveToTableButton.Tooltip = {'Save current settings and result to History Table'};
            app.SaveToTableButton.Text = 'Save To Table';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GraphSwitch = uiswitch(app.PlotTab, 'slider');
            app.GraphSwitch.Items = {'Queue Length', 'Doctors Utilization'};
            app.GraphSwitch.ValueChangedFcn = createCallbackFcn(app, @refreshplot, true);
            app.GraphSwitch.Value = 'Queue Length';
            app.OverallAverageWaitingTimeinQueueLabel = uilabel(app.PlotTab);
            app.OverallAverageWaitingTimeinQueueLabel.Text = 'Overall Average Waiting Time in Queue:';
            app.minutesLabel = uilabel(app.PlotTab);
            app.minutesLabel.Text = '0 minutes';
            app.RunSpinnerLabel = uilabel(app.PlotTab);
            app.RunSpinnerLabel.Text = 'Run';
            app.RunSpinner = uispinner(app.PlotTab);
            app.RunSpinner.ValueChangedFcn = createCallbackFcn(app, @RunListBoxValueChanged, true);
            app.ShowOverallUtilizationCheckBox = uicheckbox(app.PlotTab);
            app.ShowOverallUtilizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowOverallUtilizationCheckBoxValueChanged, true);
            app.ShowOverallUtilizationCheckBox.Text = 'Show Overall Utilization';
            app.PatientsTreatedinRunLabel = uilabel(app.PlotTab);
            app.PatientsTreatedinRunLabel.Text = 'Patients Treated in Run: ';
            app.patientsLabel = uilabel(app.PlotTab);
            app.patientsLabel.Text = '0 patients';
            app.OverallAveragePatientsTreatedLabel = uilabel(app.PlotTab);
            app.OverallAveragePatientsTreatedLabel.Text = 'Overall Average Patients Treated:';
            app.patientsLabel_2 = uilabel(app.PlotTab);
            app.patientsLabel_2.Text = '0 patients';
            app.AverageWaitingTimeinQueueinRunLabel = uilabel(app.PlotTab);
            app.AverageWaitingTimeinQueueinRunLabel.Text = 'Average Waiting Time in Queue in Run:';
            app.minutesLabel_2 = uilabel(app.PlotTab);
            app.minutesLabel_2.Text = '0 minutes';
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'Time'; 'Type'; 'Doctor'; 'Patient'; 'Compl'; 'Urgent'; 'QueueSize'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @refreshplot, true);
            app.RunSpinner_2Label = uilabel(app.DataTab);
            app.RunSpinner_2Label.Text = 'Run';
            app.RunSpinner_2 = uispinner(app.DataTab);
            app.RunSpinner_2.ValueChangedFcn = createCallbackFcn(app, @RunSpinner_2ValueChanged, true);
            app.HistoryTab = uitab(app.TabGroup);
            app.HistoryTab.Title = 'History';
            app.UITable2 = uitable(app.HistoryTab);
            app.UITable2.ColumnName = {'ID'; 'Doctors'; 'AVGPatients'; 'AWT'; 'Selection'; 'Total'; 'Scenario'; 'AI'; 'PpI'; 'CI'; 'NoE'};
            app.UITable2.ColumnRearrangeable = 'on';
            app.UITable2.RowName = {};
            app.UITable2.ColumnSortable = true;
            app.CMCIRunsTab = uitab(app.TabGroup);
            app.CMCIRunsTab.Title = 'CM & CI | Runs';
            app.UIAxes2 = uiaxes(app.CMCIRunsTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.DentalCenterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PatientsDisplay

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DentalCenterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DentalCenterUIFigure)
        end
    end
end